classdef ElectronicAnalysisGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        UcDataNotLoadedLampLabel        matlab.ui.control.Label
        UAppDataNotLoadedLampLabel      matlab.ui.control.Label
        UcDataNotLoadedLamp             matlab.ui.control.Lamp
        UAppDataNotLoadedLamp           matlab.ui.control.Lamp
        LoadPlotUcDataButton            matlab.ui.control.Button
        LoadPlotUappDataUappDataButton  matlab.ui.control.Button
        PlasmaDischargeAnalysisToolLabel  matlab.ui.control.Label
        nFLabel                         matlab.ui.control.Label
        CapacitanceTextBox              matlab.ui.control.NumericEditField
        CapacitanceEditFieldLabel       matlab.ui.control.Label
        UcFilePathTextBox               matlab.ui.control.EditField
        UcFileLabel                     matlab.ui.control.Label
        UappFilePathEditField           matlab.ui.control.EditField
        UappFileLabel                   matlab.ui.control.Label
        DataTabs                        matlab.ui.container.TabGroup
        TimeResolvedSignalPlotsTab      matlab.ui.container.Tab
        UIAxesHighCapacitorChargeVsTime  matlab.ui.control.UIAxes
        UIAxesCapacitorVoltageVsTime    matlab.ui.control.UIAxes
        UIAxesTimeResolvedVoltagePlot   matlab.ui.control.UIAxes
        TimeResolvedSignalPropertiesTab  matlab.ui.container.Tab
        UITableTimeResolvedSignalProperties  matlab.ui.control.Table
        ExportParameterstoCSVButton     matlab.ui.control.Button
        LissajousPlotPropertiesTab      matlab.ui.container.Tab
        LissajousPropertiesLabel        matlab.ui.control.Label
        ULissajousPlotNotLoadedLampLabel  matlab.ui.control.Label
        ULissajousPlotNotLoadedLamp     matlab.ui.control.Lamp
        UITableLissajousProperties      matlab.ui.control.Table
        RequiresBothDatasetstobeloadedLabel  matlab.ui.control.Label
        PlotLissajousFigureButton       matlab.ui.control.Button
        SmoothingFunctionDropDown       matlab.ui.control.DropDown
        SmoothingFunctionDropDownLabel  matlab.ui.control.Label
        UIAxesLissajousPlot             matlab.ui.control.UIAxes
    end


    % Public properties that correspond to the Simulink model
    properties (Access = public, Transient)
        Simulation simulink.Simulation
    end

    
    properties (Access = private)
        Data = struct('Uapp', [], 'Uc', [], 'Q', []);
    end
    
    methods (Access = private)
        % Calculates the dominant frequency of a signal using FFT
        function freq = estimateFrequency(~, t, x)
            % Estimate sampling frequency from time vector
            Fs = 1/mean(diff(t));
        
            % Number of samples
            N = length(x);
        
            % Remove DC offset
            x = x - mean(x);
            
            % Compute FFT
            Y = fft(x);
        
            % Two-sided spectrum scaled by N
            P2 = abs(Y/N);
        
            % Convert to one-sided spectrum
            P1 = P2(1:floor(N/2)+1);
            P1(2:end-1) = 2*P1(2:end-1);
        
            % Frequency axis
            f = Fs*(0:floor(N/2))/N;
        
            % Find peak frequency (skip DC)
            [~, idx] = max(P1(2:end));
        
            % Convert index to frequency
            freq = f(idx+1);
        end
        
        
        % Retrieves capacitance value (entered in nF) and converts to farads
        function C = getCapacitance(app)
            C = app.CapacitanceTextBox.Value * 1e-9;  % nF → F
        end
        
        
        % Converts voltage-over-capacitor signal Uc(t) into charge Q(t)
        function Q = calculateCharge(app, Uc)
            % Preallocate output array: [time, charge]
            Q = zeros(length(Uc(:, 1)), 2);
        
            % Compute charge Q = C * Uc for every time sample
            Q(:, 2) = app.getCapacitance() * Uc(:, 2);
        
            % Copy time column
            Q(:, 1) = Uc(:, 1);
        end
        
        
        % Computes various descriptive statistics for a signal x(t)
        function stats = computeSignalStats(app, t, x)
            % Compute statistics safely; return NaN structure if something fails
            try
                stats.ptp      = max(x) - min(x);      % peak-to-peak amplitude
                stats.rms      = sqrt(mean(x.^2));      % root-mean-square
                stats.avg      = mean(x);               % average value
                stats.max      = max(x);                % maximum value
                stats.min      = min(x);                % minimum value
                stats.std      = std(x);                % standard deviation
                stats.var      = var(x);                % variance
                stats.freq     = app.estimateFrequency(t, x); % dominant freq
                stats.n        = numel(x);              % number of samples
                stats.duration = t(end) - t(1);         % total time span
            catch
                stats = NaN;
            end
        end
        
        
        % Writes computed statistics into the corresponding column of a UITable
        function updateTimeResolvedPropertiesTable(app, colIdx, stats)
        
            % Retrieve table data
            data = app.UITableTimeResolvedSignalProperties.Data;
        
            % Insert statistics in correct rows for this column
            data{1,  colIdx} = stats.ptp;
            data{2,  colIdx} = stats.rms;
            data{3,  colIdx} = stats.avg;
            data{4,  colIdx} = stats.max;
            data{5,  colIdx} = stats.min;
            data{6,  colIdx} = stats.std;
            data{7,  colIdx} = stats.var;
            data{8,  colIdx} = stats.freq;
            data{9,  colIdx} = stats.n;
            data{10, colIdx} = stats.duration;
        
            % Push updated data back to the table
            app.UITableTimeResolvedSignalProperties.Data = data;
        end
        
        
        % Loads a data file, plots it, computes stats, and updates the UI table
        function data = loadAndPlot(app, filePath, axesHandle, color, label, colIdx)
            % Read two-column data file: [time, signal]
            data = readmatrix(filePath);
            
            t = data(:,1);
            x = data(:,2);
        
            % Clear target axes and plot the signal
            cla(axesHandle);
            plot(axesHandle, t, x, 'LineWidth', 1.4, 'Color', color);
            legend(axesHandle, label);
        
            % Compute statistics for this signal
            stats = computeSignalStats(app, t, x);
        
            % Update the UI with new statistics
            app.updateTimeResolvedPropertiesTable(colIdx, stats);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

        end

        % Button pushed function: LoadPlotUappDataUappDataButton
        function LoadPlotUappDataUappDataButtonPushed(app, event)

            uapp_file_path = app.UappFilePathEditField.Value;
            if isfile(uapp_file_path)
                try
                    app.Data.Uapp = app.loadAndPlot(uapp_file_path, app.UIAxesTimeResolvedVoltagePlot, 'b', 'Uapp Voltage (V)', 1);
                    
                    app.UAppDataNotLoadedLamp.Color = [0 1 0];
                    app.UAppDataNotLoadedLampLabel.Text = "Loaded";
                catch ME
                    uialert(app.UIFigure, sprintf("Error: %s", ME.message), "Read Error");
                end
            else
                uialert(app.UIFigure, "File does not exist.", "File Error");
            end
        end

        % Button pushed function: LoadPlotUcDataButton
        function LoadPlotUcDataButtonPushed(app, event)
            uc_file_path = app.UcFilePathTextBox.Value;
        
            if isfile(uc_file_path)
                try
                    app.Data.Uc = app.loadAndPlot(uc_file_path, app.UIAxesCapacitorVoltageVsTime, 'g', 'Capacitor Voltage (V)', 2);
                    app.Data.Q = app.calculateCharge(app.Data.Uc);
                    
                    t = app.Data.Q(:,1);
                    x = app.Data.Q(:,2);
                
                    cla(app.UIAxesHighCapacitorChargeVsTime);
                    plot(app.UIAxesHighCapacitorChargeVsTime, t, x, 'LineWidth', 1.4, 'Color', 'r');
                    legend(app.UIAxesHighCapacitorChargeVsTime, 'Capacitor Charge (Q)');
                    q_stats = app.computeSignalStats(t, x);
                    app.updateTimeResolvedPropertiesTable(3, q_stats);

                    app.UcDataNotLoadedLamp.Color = [0 1 0];
                    app.UcDataNotLoadedLampLabel.Text = "Loaded";

                catch
                    uialert(app.UIFigure, "Could not read the Uc file.", "Read Error");
                end
            else
                uialert(app.UIFigure, "Uc file does not exist.", "File Error");
            end
        end

        % Button pushed function: PlotLissajousFigureButton
        function PlotLissajousFigureButtonPushed(app, event)
            if ~isempty(app.Data.Q) && ~isempty(app.Data.Uapp)
                % Get smoothing function currently selected
        
                U = app.Data.Uapp(:, 2);
                Q = app.Data.Q(:, 2);
                
                smoothing_value = app.SmoothingFunctionDropDown.Value;
        
                is_smoothing = false;
        
                
                switch smoothing_value
                    case 'None'
                        disp ("No smoothing applied");
        
                    case "Moving Average Smoothing"
                        windowSize = 5;
                        is_smoothing = true;
                        Q_smoothed = movmean(Q, windowSize);
                        U_smoothed = movmean(U, windowSize);
                end
            
                % Clear the axes before plotting
                cla(app.UIAxesLissajousPlot);
                
                % Plot Q on the same axes
                if is_smoothing 
                    plot(app.UIAxesLissajousPlot, U_smoothed, Q_smoothed, 'LineWidth', 1.4, 'Color', 'r', 'DisplayName', 'Smoothed');
                    hold(app.UIAxesLissajousPlot, 'on');
                    plot(app.UIAxesLissajousPlot, U, Q, 'LineWidth', 0.5, 'Color', [0.5 0.5 0.5], 'LineStyle', ':', 'DisplayName', 'Original');
                    hold(app.UIAxesLissajousPlot, 'off');
                    legend(app.UIAxesLissajousPlot, 'show');
                else 
                    plot(app.UIAxesLissajousPlot, U, Q, 'LineWidth', 1.4, 'Color', 'r', 'DisplayName', 'Uapp over QData');
                    legend(app.UIAxesLissajousPlot, 'show');
                end
                
                % Set axis labels and title
                xlabel(app.UIAxesLissajousPlot, 'Uapp (V)');
                ylabel(app.UIAxesLissajousPlot, 'Q');
                title(app.UIAxesLissajousPlot, 'Lissajous Figure: Q vs Uapp (V)');
                grid(app.UIAxesLissajousPlot, 'on');
        
                % area of closed loop (Joules per cycle) 
                S = polyarea(U, Q);
                f = 35000; 
                
                P = S * f;
                P_min = P * 0.95;
                P_max = P * 1.05;

                disp (S);
                data = app.UITableLissajousProperties.Data;
                data{1,1} = 'S';   % char, acceptable
                data{1,2} = S;

                data{2, 1} = 'P';
                data{2, 2} = P;
                data{3, 1} = 'P_min';
                data{3, 2} = P_min;
                data{4, 1} = 'P_max';
                data{4, 2} = P_max;

                app.UITableLissajousProperties.Data = data;
                app.ULissajousPlotNotLoadedLamp.Color = [0 1 0];
                app.ULissajousPlotNotLoadedLampLabel.Text = "Loaded";

            else
                uialert(app.UIFigure, "UCData or UApp data has not been retrieved", "Attribute Error");
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.DataTabs = uitabgroup(app.UIFigure);
            app.TimeResolvedSignalPlotsTab = uitab(app.DataTabs);
            app.TimeResolvedSignalPlotsTab.Title = 'Time-Resolved Signal Plots';
            app.UIAxesTimeResolvedVoltagePlot = uiaxes(app.TimeResolvedSignalPlotsTab);
            title(app.UIAxesTimeResolvedVoltagePlot, 'Time-resolved voltage (V) plot')
            xlabel(app.UIAxesTimeResolvedVoltagePlot, 'Time (s)')
            ylabel(app.UIAxesTimeResolvedVoltagePlot, 'Voltage (V)')
            app.UIAxesTimeResolvedVoltagePlot.SubtitleFontWeight = 'bold';
            app.UIAxesCapacitorVoltageVsTime = uiaxes(app.TimeResolvedSignalPlotsTab);
            title(app.UIAxesCapacitorVoltageVsTime, 'Capacitor voltage (V) vs time (s) plot')
            xlabel(app.UIAxesCapacitorVoltageVsTime, 'Time (s)')
            ylabel(app.UIAxesCapacitorVoltageVsTime, 'Voltage (V)')
            app.UIAxesCapacitorVoltageVsTime.SubtitleFontWeight = 'bold';
            app.UIAxesHighCapacitorChargeVsTime = uiaxes(app.TimeResolvedSignalPlotsTab);
            title(app.UIAxesHighCapacitorChargeVsTime, 'Capacitor charge (Q) vs time (s) plot')
            xlabel(app.UIAxesHighCapacitorChargeVsTime, 'Time (s)')
            ylabel(app.UIAxesHighCapacitorChargeVsTime, 'Charge (Q)')
            app.TimeResolvedSignalPropertiesTab = uitab(app.DataTabs);
            app.TimeResolvedSignalPropertiesTab.Title = 'Time-Resolved Signal Properties';
            app.ExportParameterstoCSVButton = uibutton(app.TimeResolvedSignalPropertiesTab, 'push');
            app.ExportParameterstoCSVButton.Text = 'Export Parameters to CSV';
            app.UITableTimeResolvedSignalProperties = uitable(app.TimeResolvedSignalPropertiesTab);
            app.UITableTimeResolvedSignalProperties.ColumnName = {'Uapp Time Resolved Voltage (V)'; 'Uc Capactior Voltage (V)'; 'Uc Capacitor Charge (Q)'; 'Units'};
            app.UITableTimeResolvedSignalProperties.RowName = {'Peak-to-Peak '; 'RMS (Root Mean Square)'; 'Average (Mean)'; 'Maximum Value'; 'Minimum Value'; 'Standard Deviation'; 'Variance'; 'Frequency (Estimated)'; 'Number of Samples'; 'Time Duration'};
            app.LissajousPlotPropertiesTab = uitab(app.DataTabs);
            app.LissajousPlotPropertiesTab.Title = 'Lissajous Plot & Properties';
            app.UIAxesLissajousPlot = uiaxes(app.LissajousPlotPropertiesTab);
            title(app.UIAxesLissajousPlot, 'Lissajous Figure: Y = Q, X =Uapp (V)')
            xlabel(app.UIAxesLissajousPlot, 'Uapp (V)')
            ylabel(app.UIAxesLissajousPlot, 'Q')
            zlabel(app.UIAxesLissajousPlot, 'Z')
            app.SmoothingFunctionDropDownLabel = uilabel(app.LissajousPlotPropertiesTab);
            app.SmoothingFunctionDropDownLabel.Text = 'Smoothing Function';
            app.SmoothingFunctionDropDown = uidropdown(app.LissajousPlotPropertiesTab);
            app.SmoothingFunctionDropDown.Items = {'None', 'Moving Average Smoothing'};
            app.SmoothingFunctionDropDown.Value = 'None';
            app.PlotLissajousFigureButton = uibutton(app.LissajousPlotPropertiesTab, 'push');
            app.PlotLissajousFigureButton.ButtonPushedFcn = createCallbackFcn(app, @PlotLissajousFigureButtonPushed, true);
            app.PlotLissajousFigureButton.Text = 'Plot Lissajous Figure';
            app.RequiresBothDatasetstobeloadedLabel = uilabel(app.LissajousPlotPropertiesTab);
            app.RequiresBothDatasetstobeloadedLabel.Text = '[Requires Both Datasets to be loaded]';
            app.UITableLissajousProperties = uitable(app.LissajousPlotPropertiesTab);
            app.UITableLissajousProperties.ColumnName = {'Parameter'; 'Value'; ''; ''};
            app.UITableLissajousProperties.RowName = {};
            app.ULissajousPlotNotLoadedLamp = uilamp(app.LissajousPlotPropertiesTab);
            app.ULissajousPlotNotLoadedLampLabel = uilabel(app.LissajousPlotPropertiesTab);
            app.ULissajousPlotNotLoadedLampLabel.Text = 'Not Loaded';
            app.LissajousPropertiesLabel = uilabel(app.LissajousPlotPropertiesTab);
            app.LissajousPropertiesLabel.Text = 'Lissajous Properties: ';
            app.UappFileLabel = uilabel(app.UIFigure);
            app.UappFileLabel.Text = 'Uapp File: ';
            app.UappFilePathEditField = uieditfield(app.UIFigure, 'text');
            app.UappFilePathEditField.Tag = 'uapp_file_path';
            app.UappFilePathEditField.Value = 'C:\Users\ryanm\OneDrive\Desktop\ELEC431-Coursework\electronic-analysis-gui\Signals\Signals\Uapp.txt';
            app.UcFileLabel = uilabel(app.UIFigure);
            app.UcFileLabel.Text = ' Uc File:      ';
            app.UcFilePathTextBox = uieditfield(app.UIFigure, 'text');
            app.UcFilePathTextBox.Value = 'C:\Users\ryanm\OneDrive\Desktop\ELEC431-Coursework\electronic-analysis-gui\Signals\Signals\Uc.txt';
            app.CapacitanceEditFieldLabel = uilabel(app.UIFigure);
            app.CapacitanceEditFieldLabel.Text = 'Capacitance: ';
            app.CapacitanceTextBox = uieditfield(app.UIFigure, 'numeric');
            app.CapacitanceTextBox.Value = 22;
            app.nFLabel = uilabel(app.UIFigure);
            app.nFLabel.Text = 'nF';
            app.PlasmaDischargeAnalysisToolLabel = uilabel(app.UIFigure);
            app.PlasmaDischargeAnalysisToolLabel.Text = 'Plasma Discharge Analysis Tool';
            app.LoadPlotUappDataUappDataButton = uibutton(app.UIFigure, 'push');
            app.LoadPlotUappDataUappDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadPlotUappDataUappDataButtonPushed, true);
            app.LoadPlotUappDataUappDataButton.Text = 'Load & Plot Uapp Data Uapp Data';
            app.LoadPlotUcDataButton = uibutton(app.UIFigure, 'push');
            app.LoadPlotUcDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadPlotUcDataButtonPushed, true);
            app.LoadPlotUcDataButton.Text = 'Load & Plot Uc Data';
            app.UAppDataNotLoadedLamp = uilamp(app.UIFigure);
            app.UcDataNotLoadedLamp = uilamp(app.UIFigure);
            app.UAppDataNotLoadedLampLabel = uilabel(app.UIFigure);
            app.UAppDataNotLoadedLampLabel.Text = 'Not Loaded';
            app.UcDataNotLoadedLampLabel = uilabel(app.UIFigure);
            app.UcDataNotLoadedLampLabel.Text = 'Not Loaded';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ElectronicAnalysisGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end